classdef PatientInfo_APP_2Panel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SearchButton                  matlab.ui.control.Button
        DOBEditField                  matlab.ui.control.EditField
        DOBEditFieldLabel             matlab.ui.control.Label
        SexEditField                  matlab.ui.control.EditField
        SexEditFieldLabel             matlab.ui.control.Label
        LastNameEditField             matlab.ui.control.EditField
        LastNameEditFieldLabel        matlab.ui.control.Label
        FirstNameEditField            matlab.ui.control.EditField
        FirstNameEditFieldLabel       matlab.ui.control.Label
        AddNewPatientButton           matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        InputButton                   matlab.ui.control.Button
        PlotButton                    matlab.ui.control.Button
        PatientTrendDropDown          matlab.ui.control.DropDown
        PatientTrendDropDownLabel     matlab.ui.control.Label
        DateofVisitDatePicker         matlab.ui.control.DatePicker
        DateofVisitDatePickerLabel    matlab.ui.control.Label
        InputNewPatientDataLabel      matlab.ui.control.Label
        RestingHeartRateBPMEditField  matlab.ui.control.NumericEditField
        RestingHeartRateBPMEditFieldLabel  matlab.ui.control.Label
        DiastolicBloodPressuremmHgEditField  matlab.ui.control.NumericEditField
        DiastolicBloodPressuremmHgEditFieldLabel  matlab.ui.control.Label
        SystolicBloodPressuremmHgEditField  matlab.ui.control.NumericEditField
        SystolicBloodPressuremmHgEditFieldLabel  matlab.ui.control.Label
        BodyTempCEditField            matlab.ui.control.NumericEditField
        BodyTempCEditFieldLabel       matlab.ui.control.Label
        WeightKgEditField             matlab.ui.control.NumericEditField
        WeightKgEditFieldLabel        matlab.ui.control.Label
        HeightcmEditField             matlab.ui.control.NumericEditField
        HeightcmLabel                 matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end


    % Every variable you use has to go in here and call it with app.XXXX
    properties (Access = private)
        
        Database = readtable("Patient Database.xlsx") % Description
        PatientFirstName = [];
        PatientLastName = [];
        PatientSex = [];
        PatientDOB = [];
        userDOB

        SheetName = [];
        PatientSpecific = [];
        parameter = {};

        WeightField = [];
        HeightField = [];
        HeartRateField = [];
        BodyTempField = [];
        BloodPressureSYSField = [];
        BloodPressureDIAField = [];
        time = [];
        y = [];
        hrmodel 
        sysmodel
        diamodel


    end
   
    
    methods (Access = private)
        
function addFunctions(app)

    hr = load("HRModel.mat");
    sys = load("SystolicModel.mat");
    dia = load("DiastolicModel.mat");

    app.hrmodel = hr;
    app.sysmodel = sys;
    app.diamodel = dia;

end

function trendGenerator(app)

    hr = heartratemodel.predictFcn(app.Database);
    plot(hr,"m")

end

 
function addVisitToExcel(app)
% I'll write documentation later

    % ------------ BMI Calculation ------------
    BMI = app.WeightField / (app.HeightField^2);

    if BMI < 18.5
            category = "Underweight";
        elseif BMI < 25
            category = "Optimal";
        elseif BMI < 30
            category = "Overweight";
        else
            category = "Obese";
    end
    
    % -------------- Body Temperature --------------
    if app.BodyTempField > 100.4
        tempcat = "Fever";
    elseif app.BodyTempField < 95
        tempcat = "Hypothermia";
    else 
        tempcat = "Normal Temperature";
    end
    
    % ------------ Blood Pressure Categorization ------------
    if app.BloodPressureSYSField >= 160
            bp = "Stage 2 Hypertension";
    elseif app.BloodPressureSYSField >= 140
        if app.BloodPressureDIAField >= 100
            bp = "Stage 2 Hypertension";
        else 
            bp = "Stage 1 Hypertension";
        end
    elseif app.BloodPressureSYSField >= 120
        if app.BloodPressureDIAField >= 100 
            bp = "Stage 2 Hypertension";
        elseif app.BloodPressureDIAField >= 90
            bp = "Stage 1 Hypertension";
        else 
            bp = "Pre Hypertension";
        end
    elseif app.BloodPressureSYSField >= 90
        if app.BloodPressureDIAField >= 100
            bp = "Stage 2 Hypertension";
        elseif app.BloodPressureDIAField >= 90
            bp = "Stage 1 Hypertension";
        elseif app.BloodPressureDIAField >= 80 
            bp = "Pre Hypertension";
        else
            bp = "Normal";
        end
    elseif app.BloodPressureSYSField < 90
        if app.BloodPressureDIAField >= 100
            bp = "Stage 2 Hypertension";
        elseif app.BloodPressureDIAField >= 90
            bp = "Stage 1 Hypertension";
        elseif app.BloodPressureDIAField >= 80
            bp = "Pre Hypertension";
        elseif app.BloodPressureDIAField >= 60
            bp = "Normal";
        else
            bp = "Low";
        end
    end
    dbp = ((2 * app.BloodPressureDIAField) + app.BloodPressureSYSField) / 3;
    
    % ------------ Load or create Visits sheet ------------
    try
        opts = detectImportOptions(filename, 'Sheet', 'Visits');  % opens excel file
        visits = readtable(filename, opts);                       % finds sheet named "Visit"
        newRow = false;                                           % Indicates exisiting file is being updated
    catch
        % If the file or sheet doesn't exist, start a new one
        visits = table();                                         
        newRow = true;
    end

    % ------------ Append the new visit ------------
    newEntry = table( ...
        string(visitDate), app.HeightField, app.WeightField, BMI, string(category), app.BodyTempField, string(tempcat), dbp, string(bp), ...
        'VariableNames', {'Date','Height_m','Weight_kg','BMI','BMI_Category','Temperature','Temp_Category','Blood Pressure','Blood Pressure Category'} ...
    );

    visits = [visits; newEntry];

    % ------------ Write back to Excel ------------
    writetable(visits, filename, 'Sheet', 'Visits', 'WriteMode', 'overwrite');


    % If profile sheet doesn’t exist, give the user a reminder
    if newRow
        fprintf('File created. Be sure to add patient name + DOB in the "Profile" sheet.\n');
    end

    fprintf('Visit recorded for file: %s\n', filename);
end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)


                try
                    app.userDOB = datetime(app.DOBEditField.Value, 'InputFormat','MM/dd/uuuu');

                catch
                    uialert(app.UIFigure, "Enter DOB as MM/dd/YYYY", "Invalid Date")
                    return
                end


                % Convert database DOBs to strings with SAME format
                DOBbase = string(datetime(app.Database.DOB, 'Format', 'MM/dd/yyyy'));
                
                % Convert user input to the same string format
                app.userDOB = string(datetime(app.DOBEditField.Value, 'InputFormat','MM/dd/uuuu', 'Format','MM/dd/yyyy'));
                
                % Now compare STRING TO STRING
                idx = strcmp(DOBbase, app.userDOB);
                

                if any(idx)
                    patient = app.Database(idx,:);

                    app.PatientFirstName.Text = patient.FirstName;
                    app.PatientLastName.Text  = patient.LastName;
                    app.PatientSex.Text       = patient.Sex;
                    app.PatientDOB.Text       = datetime(patient.DOB);

                    app.SheetName.Text        = app.PatientLastName.Text;
                    

                    app.PatientSpecific = readtable("Patient Database.xlsx","Sheet", app.SheetName.Text{1});
                    
                    app.RightPanel.Enable = 'on';                  

                    
                else
                    uialert(app.UIFigure, "No patient found", "Not Found");
                end

        end

        % Button pushed function: AddNewPatientButton
        function AddNewPatientButtonPushed(app, event)

% Write new table with all of the information in the correct place

% Concatenate the new patient 1x6 w (app.Database, 'Sheet', 1)

    uialert(app.UIFigure, "Patient added successfully!", "Success");

        end

        % Button pushed function: InputButton
        function InputButtonPushed(app, event)
            % All of the calculations go here 
            % Display the calculated measurements with uialert
            % Append all of the input variables to the specific patient
            %   table w (app.Database, 'Sheet', 'Last_name_of_patient')
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            % This part works
            app.parameter.Text = app.PatientTrendDropDown.Value;

            if app.parameter.Text == "Weight"
                app.y = [app.PatientSpecific.Weight];
            elseif app.parameter.Text == "BMI"
                app.y = BMI_Calc;
            elseif app.parameter.Text == "BloodPressure"
                app.y = BP_Calc;
            elseif app.parameter.Text == "HeartRate"
                app.y = [app.PatientSpecific.HeartRate];
            end


            % This part doesnt -- youll have to make a cell matrix with
            %   datetimes because thats how its saved
            [app.time] = app.PatientSpecific.Date(2:end);

            % Shit doesnt work because i dont have the calculations so
            %   maybe it will maybe it wont
            uialert(app.UIFigure,"Patient Trend","Info",plot(app.time),app.y)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AddNewPatientButton = uibutton(app.LeftPanel, 'push');
            app.AddNewPatientButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewPatientButtonPushed, true);
            app.AddNewPatientButton.Text = 'Add New Patient';
            app.FirstNameEditFieldLabel = uilabel(app.LeftPanel);
            app.FirstNameEditFieldLabel.Text = 'First Name';
            app.FirstNameEditField = uieditfield(app.LeftPanel, 'text');
            app.LastNameEditFieldLabel = uilabel(app.LeftPanel);
            app.LastNameEditFieldLabel.Text = 'Last Name';
            app.LastNameEditField = uieditfield(app.LeftPanel, 'text');
            app.SexEditFieldLabel = uilabel(app.LeftPanel);
            app.SexEditFieldLabel.Text = 'Sex';
            app.SexEditField = uieditfield(app.LeftPanel, 'text');
            app.DOBEditFieldLabel = uilabel(app.LeftPanel);
            app.DOBEditFieldLabel.Text = 'DOB';
            app.DOBEditField = uieditfield(app.LeftPanel, 'text');
            app.SearchButton = uibutton(app.LeftPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Enable = 'off';
            app.HeightcmLabel = uilabel(app.RightPanel);
            app.HeightcmLabel.Text = 'Height (cm)';
            app.HeightcmEditField = uieditfield(app.RightPanel, 'numeric');
            app.WeightKgEditFieldLabel = uilabel(app.RightPanel);
            app.WeightKgEditFieldLabel.Text = 'Weight (Kg)';
            app.WeightKgEditField = uieditfield(app.RightPanel, 'numeric');
            app.BodyTempCEditFieldLabel = uilabel(app.RightPanel);
            app.BodyTempCEditFieldLabel.Text = 'Body Temp (C)';
            app.BodyTempCEditField = uieditfield(app.RightPanel, 'numeric');
            app.SystolicBloodPressuremmHgEditFieldLabel = uilabel(app.RightPanel);
            app.SystolicBloodPressuremmHgEditFieldLabel.Text = 'Systolic Blood Pressure (mmHg)';
            app.SystolicBloodPressuremmHgEditField = uieditfield(app.RightPanel, 'numeric');
            app.DiastolicBloodPressuremmHgEditFieldLabel = uilabel(app.RightPanel);
            app.DiastolicBloodPressuremmHgEditFieldLabel.Text = 'Diastolic Blood Pressure (mmHg)';
            app.DiastolicBloodPressuremmHgEditField = uieditfield(app.RightPanel, 'numeric');
            app.RestingHeartRateBPMEditFieldLabel = uilabel(app.RightPanel);
            app.RestingHeartRateBPMEditFieldLabel.Text = 'Resting Heart Rate (BPM)';
            app.RestingHeartRateBPMEditField = uieditfield(app.RightPanel, 'numeric');
            app.InputNewPatientDataLabel = uilabel(app.RightPanel);
            app.InputNewPatientDataLabel.Text = 'Input New Patient Data';
            app.DateofVisitDatePickerLabel = uilabel(app.RightPanel);
            app.DateofVisitDatePickerLabel.Text = 'Date of Visit';
            app.DateofVisitDatePicker = uidatepicker(app.RightPanel);
            app.PatientTrendDropDownLabel = uilabel(app.RightPanel);
            app.PatientTrendDropDownLabel.Text = 'Patient Trend';
            app.PatientTrendDropDown = uidropdown(app.RightPanel);
            app.PatientTrendDropDown.Items = {'Weight', 'BMI', 'BloodPressure', 'HeartRate'};
            app.PatientTrendDropDown.Value = 'BMI';
            app.PlotButton = uibutton(app.RightPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.InputButton = uibutton(app.RightPanel, 'push');
            app.InputButton.ButtonPushedFcn = createCallbackFcn(app, @InputButtonPushed, true);
            app.InputButton.Text = 'Input';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PatientInfo_APP_2Panel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end